classdef live_telemetry < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TextArea                     matlab.ui.control.TextArea
        TextAreaLabel                matlab.ui.control.Label
        GridLayout                   matlab.ui.container.GridLayout
        FuelPressureGauge            matlab.ui.control.SemicircularGauge
        FuelPressureGauge_2Label     matlab.ui.control.Label
        LambdaGauge                  matlab.ui.control.SemicircularGauge
        LambdaGaugeLabel             matlab.ui.control.Label
        CoolantFlowGauge             matlab.ui.control.SemicircularGauge
        CoolantFlowGaugeLabel        matlab.ui.control.Label
        OilPressureGauge             matlab.ui.control.SemicircularGauge
        OilPressureGaugeLabel        matlab.ui.control.Label
        AccelerationGaugeLabel       matlab.ui.control.Label
        AccelerationGauge            matlab.ui.control.Gauge
        EngineRPMGauge_2Label        matlab.ui.control.Label
        EngineRPMGaugeLabel          matlab.ui.control.Label
        EngineRPMGauge               matlab.ui.control.Gauge
        EngineRPMGaugeLabel_2        matlab.ui.control.Label
        EngineCoolantTempGauge       matlab.ui.control.SemicircularGauge
        EngineCoolantTempGaugeLabel  matlab.ui.control.Label
        EngineOilTempGauge           matlab.ui.control.SemicircularGauge
        EngineOilTempGaugeLabel      matlab.ui.control.Label
        Acceleration                 matlab.ui.control.UIAxes
        EngineRPM                    matlab.ui.control.UIAxes
    end

    
properties (Access = private)
    accelHistory = [];  % store acceleration samples
    t              % timer object
end
    
methods (Access = private)
    function updateHistogram(app)
        % Simulate new acceleration data
        newAccel = 9.8 + randn(1, 10);

        % Append to history
        app.accelHistory = [app.accelHistory newAccel];

        % Keep only last 1000 samples
        if numel(app.accelHistory) > 1000
            app.accelHistory = app.accelHistory(end-999:end);
        end

        % Clear and redraw histogram
        cla(app.Acceleration);
        histogram(app.Acceleration, app.accelHistory, 20, ...
            'FaceColor', 'b', 'EdgeColor', 'w');
        title(app.Acceleration, 'Acceleration Histogram');
        xlabel(app.Acceleration, 'Acceleration (m/s^2)');
        ylabel(app.Acceleration, 'Frequency');
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t = timer( ...
        'ExecutionMode', 'fixedRate', ...
        'Period', 0.5, ...                 % update every 0.5 seconds
        'TimerFcn', @(~,~)updateHistogram(app));  % function to call
    start(app.t);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
        stop(app.t);
        delete(app.t);
    catch
            end
    delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EngineRPM = uiaxes(app.GridLayout);
            title(app.EngineRPM, 'Engine RPM')
            xlabel(app.EngineRPM, 'Time')
            ylabel(app.EngineRPM, 'RPM')
            zlabel(app.EngineRPM, 'Z')
            app.Acceleration = uiaxes(app.GridLayout);
            title(app.Acceleration, 'Acceleration')
            xlabel(app.Acceleration, 'Time')
            ylabel(app.Acceleration, 'Acceleration')
            zlabel(app.Acceleration, 'Z')
            app.EngineOilTempGaugeLabel = uilabel(app.GridLayout);
            app.EngineOilTempGaugeLabel.Text = 'Engine Oil Temp';
            app.EngineOilTempGauge = uigauge(app.GridLayout, 'semicircular');
            app.EngineOilTempGauge.Limits = [0 12000];
            app.EngineOilTempGauge.MajorTicks = [];
            app.EngineOilTempGauge.MinorTicks = [0 480 960 1440 1920 2400 2880 3360 3840 4320 4800 5280 5760 6240 6720 7200 7680 8160 8640 9120 9600 10080 10560 11040 11520 12000];
            app.EngineCoolantTempGaugeLabel = uilabel(app.GridLayout);
            app.EngineCoolantTempGaugeLabel.Text = 'Engine Coolant Temp';
            app.EngineCoolantTempGauge = uigauge(app.GridLayout, 'semicircular');
            app.EngineCoolantTempGauge.Limits = [0 12000];
            app.EngineCoolantTempGauge.MajorTicks = [];
            app.EngineCoolantTempGauge.MinorTicks = [0 480 960 1440 1920 2400 2880 3360 3840 4320 4800 5280 5760 6240 6720 7200 7680 8160 8640 9120 9600 10080 10560 11040 11520 12000];
            app.EngineRPMGaugeLabel_2 = uilabel(app.GridLayout);
            app.EngineRPMGaugeLabel_2.Text = 'EngineRPMGauge';
            app.EngineRPMGauge = uigauge(app.GridLayout, 'circular');
            app.EngineRPMGaugeLabel = uilabel(app.GridLayout);
            app.EngineRPMGaugeLabel.Text = 'Engine RPM';
            app.EngineRPMGauge_2Label = uilabel(app.GridLayout);
            app.EngineRPMGauge_2Label.Text = 'EngineRPMGauge';
            app.AccelerationGauge = uigauge(app.GridLayout, 'circular');
            app.AccelerationGaugeLabel = uilabel(app.GridLayout);
            app.AccelerationGaugeLabel.Text = 'Acceleration';
            app.OilPressureGaugeLabel = uilabel(app.GridLayout);
            app.OilPressureGaugeLabel.Text = 'Oil Pressure';
            app.OilPressureGauge = uigauge(app.GridLayout, 'semicircular');
            app.OilPressureGauge.Limits = [0 12000];
            app.OilPressureGauge.MajorTicks = [];
            app.CoolantFlowGaugeLabel = uilabel(app.GridLayout);
            app.CoolantFlowGaugeLabel.Text = 'Coolant Flow';
            app.CoolantFlowGauge = uigauge(app.GridLayout, 'semicircular');
            app.CoolantFlowGauge.Limits = [0 12000];
            app.CoolantFlowGauge.MajorTicks = [];
            app.CoolantFlowGauge.MinorTicks = [0 480 960 1440 1920 2400 2880 3360 3840 4320 4800 5280 5760 6240 6720 7200 7680 8160 8640 9120 9600 10080 10560 11040 11520 12000];
            app.LambdaGaugeLabel = uilabel(app.GridLayout);
            app.LambdaGaugeLabel.Text = 'Lambda';
            app.LambdaGauge = uigauge(app.GridLayout, 'semicircular');
            app.LambdaGauge.Limits = [0 12000];
            app.LambdaGauge.MajorTicks = [];
            app.LambdaGauge.MinorTicks = [0 480 960 1440 1920 2400 2880 3360 3840 4320 4800 5280 5760 6240 6720 7200 7680 8160 8640 9120 9600 10080 10560 11040 11520 12000];
            app.FuelPressureGauge_2Label = uilabel(app.GridLayout);
            app.FuelPressureGauge_2Label.Text = 'Fuel Pressure';
            app.FuelPressureGauge = uigauge(app.GridLayout, 'semicircular');
            app.FuelPressureGauge.Limits = [0 12000];
            app.FuelPressureGauge.MajorTicks = [];
            app.FuelPressureGauge.MinorTicks = [0 480 960 1440 1920 2400 2880 3360 3840 4320 4800 5280 5760 6240 6720 7200 7680 8160 8640 9120 9600 10080 10560 11040 11520 12000];
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = live_telemetry

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end