classdef ui_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        TableHeaderPanel            matlab.ui.container.Panel
        TableHeaderGridLayout       matlab.ui.container.GridLayout
        AccelerationHeader          matlab.ui.control.Label
        SpeedHeader                 matlab.ui.control.Label
        TimeHeader                  matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        AccelerationLiveValuePanel  matlab.ui.container.Panel
        AccelerationLiveValueGridLayout  matlab.ui.container.GridLayout
        AccelerationUnitsLabel      matlab.ui.control.Label
        AccelerationLiveValueLabel  matlab.ui.control.Label
        SpeedLiveValuePanel         matlab.ui.container.Panel
        SpeedLiveValueGridLayout    matlab.ui.container.GridLayout
        SpeedUnitsLabel             matlab.ui.control.Label
        SpeedLiveValueLabel         matlab.ui.control.Label
        InformationPanel            matlab.ui.container.Panel
        InformationPanelGridLayout  matlab.ui.container.GridLayout
        StatusPanel                 matlab.ui.container.Panel
        SessionInfoGridLayoud_2     matlab.ui.container.GridLayout
        BestLapValueLabel           matlab.ui.control.Label
        DistanceTraveledLabel       matlab.ui.control.Label
        FuelPercentageLabel         matlab.ui.control.Label
        TireLifeLapsLabel           matlab.ui.control.Label
        TireHealthLabel             matlab.ui.control.Label
        BestLapLabel                matlab.ui.control.Label
        DistanceLabel               matlab.ui.control.Label
        FuelLabel                   matlab.ui.control.Label
        TireLifeLabel               matlab.ui.control.Label
        TireLabel                   matlab.ui.control.Label
        SessionInfoPanel            matlab.ui.container.Panel
        SessionInfoGridLayoud       matlab.ui.container.GridLayout
        PositionValueLabel          matlab.ui.control.Label
        GapTimeLabel                matlab.ui.control.Label
        LapTimeValueLabel           matlab.ui.control.Label
        LapCountLabel               matlab.ui.control.Label
        TrainingLabel               matlab.ui.control.Label
        PositionLabel               matlab.ui.control.Label
        GapLabel                    matlab.ui.control.Label
        LapTimeLabel                matlab.ui.control.Label
        LapLabel                    matlab.ui.control.Label
        SessionLabel                matlab.ui.control.Label
        DriverInfoPanel             matlab.ui.container.Panel
        DriverInfoGridLayout        matlab.ui.container.GridLayout
        DriverNumberLabel           matlab.ui.control.Label
        TeamLabel                   matlab.ui.control.Label
        DriverLabel                 matlab.ui.control.Label
        ToolbarPanel                matlab.ui.container.Panel
        ToolbarPanelGridLayout      matlab.ui.container.GridLayout
        SaveButton                  matlab.ui.control.Button
        ClearButton                 matlab.ui.control.Button
        LoadButton                  matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
        SpeedGraph                  matlab.ui.control.UIAxes
        AccelerationGraph           matlab.ui.control.UIAxes
    end

    
    % User defined variables
    properties (Access = private)
        AccelerationData = []
        SpeedData = []
        TimeData = []

        CurrentAcceleration = 0
        CurrentSpeed = 0
        CurrentTime = 0
        Timer

        lapNum = 0
        currLapTime = 0;
        lastLapTime = 0;
        bestLapTime = 0;

    end
    
    methods (Access = private)
        function time = secToLapFormat(~, seconds)
            minutes = floor(seconds/60);
            remainingSeconds = seconds - minutes * 60;
            time = sprintf('%d:%06.3f', minutes, remainingSeconds);
        end


        % replots the speed and acceleration
        function replot(app)
            plot(app.AccelerationGraph, app.TimeData, app.AccelerationData);
            plot(app.SpeedGraph, app.TimeData, app.SpeedData);
            drawnow;
        end

        % updates the info under the session info group
        function updateSessionInfo(app, distance)
            lap_calc = floor(distance / 2);
            app.currLapTime = toc;

            if(app.lapNum < lap_calc)
                app.lapNum = lap_calc;
                timeDifference = app.currLapTime - app.lastLapTime;
                gap = timeDifference - 67; % for gap

                app.LapCountLabel.Text = sprintf("%d/67", app.lapNum);
                app.LapTimeValueLabel.Text = "0:00.000";
                app.GapTimeLabel.Text = secToLapFormat(app, gap);

                if(app.bestLapTime == 0 || timeDifference < app.bestLapTime)
                    app.bestLapTime = timeDifference;
                end
                app.lastLapTime = app.currLapTime;
                app.currLapTime = 0;
            else
                lap_time = app.currLapTime - app.lastLapTime;
                lap_time_str = secToLapFormat(app, lap_time);
                app.LapTimeValueLabel.Text = lap_time_str;
            end

        end

        function updateStatusInfo(app, distance)
            laps_left = 67 - app.lapNum;
            app.TireLifeLapsLabel.Text = sprintf("%d Laps", laps_left);

            if(app.lapNum < 32)
                app.TireHealthLabel.Text = "Good";
                app.TireLifeLapsLabel.FontColor = [0.00 0.80 0.53];
                app.TireHealthLabel.FontColor = [0.00 0.80 0.53];
            elseif(app.lapNum > 50)
                app.TireHealthLabel.Text = "Bad";
                app.TireLifeLapsLabel.FontColor = [1.0, 0.2, 0.2];
                app.TireHealthLabel.FontColor = [1.0, 0.2, 0.2];
            else
                app.TireHealthLabel.Text = "Medium";
                app.TireLifeLapsLabel.FontColor = [1.00 0.80 0.00];
                app.TireHealthLabel.FontColor = [1.00 0.80 0.00];
            end

            fuel = floor(100 * app.lapNum / 67);
            app.FuelPercentageLabel.Text = sprintf("%d%%", fuel);
            app.DistanceTraveledLabel.Text = sprintf("%.2f mi", distance);
            app.BestLapValueLabel.Text = secToLapFormat(app, app.bestLapTime);
        end

        function updateTable(app)
            newRow = {app.CurrentTime, app.CurrentSpeed, app.CurrentAcceleration};
            app.UITable.Data = [newRow; app.UITable.Data];
        end

        function updateInformation(app)
            % Have points so need to use trapezoidal rule to get the
            % distance

            if length(app.TimeData) < 2
                return;
            end
            distance = trapz(app.TimeData, app.SpeedData);

            updateSessionInfo(app, distance);
            updateStatusInfo(app, distance);
        end
        
        function updatePlot(app)
            app.CurrentAcceleration = app.CurrentAcceleration * 0.92 + (rand() - 0.5) * 2.0;
            app.CurrentAcceleration = max(-20, min(20, app.CurrentAcceleration));
            newAcceleration = app.CurrentAcceleration;

            newTime = toc;
            if isempty(app.SpeedData)
                newSpeed = 0;
            else
                newSpeed = app.SpeedData(end) + app.CurrentAcceleration * 0.05;
            end

            app.TimeData = [app.TimeData, newTime];
            app.AccelerationData = [app.AccelerationData, newAcceleration];
            app.SpeedData = [app.SpeedData, newSpeed];

            % updating variables
            app.CurrentTime = newTime;
            app.CurrentSpeed = newSpeed;

            replot(app);
            updateTable(app);

            app.SpeedLiveValueLabel.Text = sprintf('%.1f', newSpeed);
            app.AccelerationLiveValueLabel.Text = sprintf('%.2f', app.CurrentAcceleration);
            updateInformation(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Getting stuff I couldn't change in the component browser
            app.SpeedGraph.Title.Color = [0.67 0.8 0.93];
            app.AccelerationGraph.Title.Color = [0.67 0.8 0.93];
            app.UITable.ColumnName = {'Time','Speed','Acceleration'};
            app.UITable.Data = table( ...
                NaT(14,1), nan(14,1), nan(14,1), ...
                'VariableNames', {'time','speed','acceleration'} ...
            );
            
            % Setting for timer
            app.Timer = timer('ExecutionMode', 'fixedRate', ...
                              'Period', 0.05, ...
                              'TimerFcn', @(~,~) app.updatePlot());

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.Timer);
            delete(app.Timer);
            delete(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if strcmp(app.Timer.Running, 'off')
                app.AccelerationData = [];
                app.SpeedData = [];
                app.TimeData = [];
                app.CurrentAcceleration = 0;
                app.CurrentSpeed = 0;
                app.CurrentTime = 0;

                start(app.Timer)
                tic
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if strcmp(app.Timer.Running, 'on')
                stop(app.Timer);
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.AccelerationData = [];
            app.SpeedData = [];
            app.TimeData = [];
            app.CurrentAcceleration = 0;
            app.lapNum = 0;
            app.currLapTime = 0;
            app.lastLapTime = 0;
            app.bestLapTime = 0;
            cla(app.AccelerationGraph);
            cla(app.SpeedGraph);

            %replot(app);
            app.AccelerationLiveValueLabel.Text = '0';
            app.SpeedLiveValueLabel.Text = '0';
            app.UITable.Data = table( ...
                NaT(14,1), nan(14,1), nan(14,1), ...
                'VariableNames', {'time','speed','acceleration'} ...
            );
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Path to JDBC driver inside project
            jdbcPath = fullfile(pwd, 'database', 'JDBC', 'postgresql-42.7.10.jar');
            
            % Add to MATLAB dynamic classpath if not already added
            if ~any(strcmp(javaclasspath('-dynamic'), jdbcPath))
                javaaddpath(jdbcPath);
            end
            
            conn = database('postgres', 'postgres', 'wacky', ...
                'Vendor', 'PostgreSQL', ...
                'Server', '127.0.0.1', ...
                'PortNumber', 5432);
            
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Path to JDBC driver inside project
            jdbcPath = fullfile(pwd, 'database', 'JDBC', 'postgresql-42.7.10.jar');
            
            % Add to MATLAB dynamic classpath if not already added
            if ~any(strcmp(javaclasspath('-dynamic'), jdbcPath))
                javaaddpath(jdbcPath);
            end
            
            conn = database('postgres', 'postgres', 'wacky', ...
                'Vendor', 'PostgreSQL', ...
                'Server', '127.0.0.1', ...
                'PortNumber', 5432);


            T = app.UITable.Data;
                        
            validRows = ~isnat(T.time) & ~isnan(T.speed) & ~isnan(T.acceleration);
            T = T(validRows, :);
            
            % Final check
            if height(T) == 0
                uialert(app.UIFigure, "No valid data to save.", "ERROR");
                return;
            end

            name = app.DriverLabel.Text;
            name = strrep(name, "'", "''");
            team = app.TeamLabel.Text;
            team = strrep(team, "'", "''");
            driverNumber = str2double(app.DriverNumberLabel.Text);

            sql = sprintf( ...
                "SELECT driver_id FROM driver WHERE name = '%s' AND driver_number = %d", ...
                name, driverNumber);
            data = fetch(conn, sql);

            if ~isempty(data)
                driver_id = data.driver_id(1);
            else
                sql = sprintf( ...
                    "INSERT INTO Driver (name, team, driver_number) VALUES ('%s','%s',%d) RETURNING driver_id", ...
                    name, team, driverNumber);
                result = fetch(conn, sql);
                driver_id = result.driver_id(1);
            end

            todayStr = datestr(datetime("today"), 'yyyy-mm-dd');
            sql = sprintf( ...
                "INSERT INTO Session (driver_id, session_date) VALUES (%d, '%s') RETURNING session_id", ...
                driver_id, todayStr);
            result = fetch(conn, sql);
            session_id = result.session_id(1);

            n = height(T);

            T.session_id = repmat(session_id, n, 1);
            T.driver_id = repmat(driver_id, n, 1);

            T = T(:, {'time','session_id','driver_id','speed','acceleration'});
            insert(conn,"Telemetry", ["time","session_id","driver_id","speed","acceleration"], T);

            uialert(app.UIFigure, "Run saved successfully", "SUCCESS");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AccelerationGraph = uiaxes(app.GridLayout);
            title(app.AccelerationGraph, 'Acceleration Graph')
            xlabel(app.AccelerationGraph, 'Time')
            ylabel(app.AccelerationGraph, 'Acceleration')
            zlabel(app.AccelerationGraph, 'Z')
            app.SpeedGraph = uiaxes(app.GridLayout);
            title(app.SpeedGraph, 'Speed Graph')
            xlabel(app.SpeedGraph, 'Time')
            ylabel(app.SpeedGraph, 'Speed')
            zlabel(app.SpeedGraph, 'Z')
            app.ToolbarPanel = uipanel(app.GridLayout);
            app.ToolbarPanel.Title = 'Toolbar';
            app.ToolbarPanelGridLayout = uigridlayout(app.ToolbarPanel);
            app.RunButton = uibutton(app.ToolbarPanelGridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = '▶Run';
            app.StopButton = uibutton(app.ToolbarPanelGridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = '■Stop';
            app.LoadButton = uibutton(app.ToolbarPanelGridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ClearButton = uibutton(app.ToolbarPanelGridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SaveButton = uibutton(app.ToolbarPanelGridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.InformationPanel = uipanel(app.GridLayout);
            app.InformationPanel.Title = 'Information';
            app.InformationPanelGridLayout = uigridlayout(app.InformationPanel);
            app.DriverInfoPanel = uipanel(app.InformationPanelGridLayout);
            app.DriverInfoPanel.Title = 'Driver Info';
            app.DriverInfoGridLayout = uigridlayout(app.DriverInfoPanel);
            app.DriverLabel = uilabel(app.DriverInfoGridLayout);
            app.DriverLabel.Text = 'John Sanburn';
            app.TeamLabel = uilabel(app.DriverInfoGridLayout);
            app.TeamLabel.Text = 'LiveTelemetry';
            app.DriverNumberLabel = uilabel(app.DriverInfoGridLayout);
            app.DriverNumberLabel.Text = '67';
            app.SessionInfoPanel = uipanel(app.InformationPanelGridLayout);
            app.SessionInfoPanel.Title = 'Session Info';
            app.SessionInfoGridLayoud = uigridlayout(app.SessionInfoPanel);
            app.SessionLabel = uilabel(app.SessionInfoGridLayoud);
            app.SessionLabel.Text = 'Session';
            app.LapLabel = uilabel(app.SessionInfoGridLayoud);
            app.LapLabel.Text = 'Lap';
            app.LapTimeLabel = uilabel(app.SessionInfoGridLayoud);
            app.LapTimeLabel.Text = 'Lap Time';
            app.GapLabel = uilabel(app.SessionInfoGridLayoud);
            app.GapLabel.Text = 'Gap';
            app.PositionLabel = uilabel(app.SessionInfoGridLayoud);
            app.PositionLabel.Text = 'Position';
            app.TrainingLabel = uilabel(app.SessionInfoGridLayoud);
            app.TrainingLabel.Text = 'Training';
            app.LapCountLabel = uilabel(app.SessionInfoGridLayoud);
            app.LapCountLabel.Text = '1/67';
            app.LapTimeValueLabel = uilabel(app.SessionInfoGridLayoud);
            app.LapTimeValueLabel.Text = '0:00.000';
            app.GapTimeLabel = uilabel(app.SessionInfoGridLayoud);
            app.GapTimeLabel.Text = '0s';
            app.PositionValueLabel = uilabel(app.SessionInfoGridLayoud);
            app.PositionValueLabel.Text = 'P67';
            app.StatusPanel = uipanel(app.InformationPanelGridLayout);
            app.StatusPanel.Title = 'Status';
            app.SessionInfoGridLayoud_2 = uigridlayout(app.StatusPanel);
            app.TireLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.TireLabel.Text = 'Tire';
            app.TireLifeLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.TireLifeLabel.Text = 'Tire Life';
            app.FuelLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.FuelLabel.Text = 'Fuel';
            app.DistanceLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.DistanceLabel.Text = 'Distance';
            app.BestLapLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.BestLapLabel.Text = 'Best Lap';
            app.TireHealthLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.TireHealthLabel.Text = 'Good';
            app.TireLifeLapsLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.TireLifeLapsLabel.Text = '67 Laps';
            app.FuelPercentageLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.FuelPercentageLabel.Text = '100%';
            app.DistanceTraveledLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.DistanceTraveledLabel.Text = '0mi';
            app.BestLapValueLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.BestLapValueLabel.Text = '0:000.000';
            app.SpeedLiveValuePanel = uipanel(app.GridLayout);
            app.SpeedLiveValuePanel.Title = 'Speed Live Value';
            app.SpeedLiveValueGridLayout = uigridlayout(app.SpeedLiveValuePanel);
            app.SpeedLiveValueLabel = uilabel(app.SpeedLiveValueGridLayout);
            app.SpeedLiveValueLabel.Text = '0';
            app.SpeedUnitsLabel = uilabel(app.SpeedLiveValueGridLayout);
            app.SpeedUnitsLabel.Text = 'mph';
            app.AccelerationLiveValuePanel = uipanel(app.GridLayout);
            app.AccelerationLiveValuePanel.Title = 'Acceleration Live Value';
            app.AccelerationLiveValueGridLayout = uigridlayout(app.AccelerationLiveValuePanel);
            app.AccelerationLiveValueLabel = uilabel(app.AccelerationLiveValueGridLayout);
            app.AccelerationLiveValueLabel.Text = '0';
            app.AccelerationUnitsLabel = uilabel(app.AccelerationLiveValueGridLayout);
            app.AccelerationUnitsLabel.Text = 'mi/hr²';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Time'; 'Speed'; 'Acceleration'};
            app.UITable.RowName = {};
            app.TableHeaderPanel = uipanel(app.GridLayout);
            app.TableHeaderGridLayout = uigridlayout(app.TableHeaderPanel);
            app.TimeHeader = uilabel(app.TableHeaderGridLayout);
            app.TimeHeader.Text = 'Time (s)';
            app.SpeedHeader = uilabel(app.TableHeaderGridLayout);
            app.SpeedHeader.Text = 'Speed (mph)';
            app.AccelerationHeader = uilabel(app.TableHeaderGridLayout);
            app.AccelerationHeader.Text = 'Acceleration (mi/hr²)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end